classdef trab1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        EditarTab                       matlab.ui.container.Tab
        AlgoritmoListBoxLabel           matlab.ui.control.Label
        AlgoritmoListBox                matlab.ui.control.ListBox
        MiniImagem                      matlab.ui.control.UIAxes
        AplicarButton                   matlab.ui.control.Button
        CarregarImagemTab               matlab.ui.container.Tab
        ImagemSelecionada               matlab.ui.control.UIAxes
        EncontrarButton                 matlab.ui.control.Button
        SelecioneoarquivodaimagemLabel  matlab.ui.control.Label
        ImagemAtual                     matlab.ui.control.UIAxes
        OpesPanel                       matlab.ui.container.Panel
        VoltarButton                    matlab.ui.control.Button
        LimparTudoButton                matlab.ui.control.Button
    end


    properties (Access = private)
        imagemCarregada % Description
        
        imagemProcessada % Description
        
        figureThreshold
    end

    methods (Access = private)
    
        
        function dialogThreshold(app)
            
               function aplicarThreshold(~, ~, app, Value)
                    app.imagemProcessada = im2bw(app.imagemProcessada, Value);
                    imshow(app.imagemProcessada, 'Parent', app.ImagemAtual);
                    close(app.figureThreshold);
               end
               
               app.figureThreshold = uifigure;
               app.figureThreshold.Position = [300 300 250 100];    
              
               spn = uispinner(app.figureThreshold);
               spn.Limits = [0 1];
               spn.Value = 0.5;
               spn.Step = 0.1;
               spn.Position = [150 65 60 20];
               
               txt = uilabel(app.figureThreshold);
               txt.Text = 'Valor do Threshold';
               txt.Position = [40 65 120 20];
               
               btn = uibutton(app.figureThreshold, 'ButtonPushedFcn', @(btn,event) aplicarThreshold(btn, event, app, spn.Value));
               btn.Text = 'Aplicar';
               btn.Position = [90 25 80 20];
               
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            set(app.ImagemSelecionada, 'XTick', []);
            set(app.ImagemSelecionada, 'YTick', []);
            set(app.ImagemAtual, 'XTick', []);
            set(app.ImagemAtual, 'YTick', []);
            set(app.MiniImagem, 'XTick', []);
            set(app.MiniImagem, 'YTick', []);
        end

        % Button pushed function: EncontrarButton
        function EncontrarButtonPushed(app, event)
            [filename,user_canceled] = imgetfile('InitialPath','E:\UNIOESTE\Atuais\PID\Matlab\Imagens');
            if user_canceled == false
                app.imagemCarregada = imread(filename);
                app.imagemProcessada = app.imagemCarregada;
                imshow(app.imagemCarregada, 'Parent', app.ImagemSelecionada);  
                imshow(app.imagemCarregada, 'Parent', app.MiniImagem);
            end     
            % [filename,pathname] = uigetfile({'.jpeg', '.'},'Select file');
            
            
        end

        % Button pushed function: AplicarButton
        function AplicarButtonPushed(app, event)
            value = app.AlgoritmoListBox.Value;
            if strcmp(value, 'Escala de Cinza') == 1
                app.imagemProcessada = rgb2gray(app.imagemCarregada);
                imshow(app.imagemProcessada, 'Parent', app.ImagemAtual);
            elseif strcmp(value, 'Limiarização') == 1
                dialogThreshold(app);
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.EditarTab = uitab(app.TabGroup);
            app.EditarTab.Title = 'Editar';
            app.AlgoritmoListBoxLabel = uilabel(app.EditarTab);
            app.AlgoritmoListBoxLabel.Text = 'Algoritmo';
            app.AlgoritmoListBox = uilistbox(app.EditarTab);
            app.AlgoritmoListBox.Items = {'Limiarização', 'Escala de Cinza', 'Passa-Alta', 'Passa-Baixa', 'Roberts', 'Prewitt', 'Sobel', 'Log', 'Zerocross', 'Canny', 'Ruídos', 'Watershed', 'Contagem de Objetos'};
            app.AlgoritmoListBox.Value = 'Limiarização';
            app.MiniImagem = uiaxes(app.EditarTab);
            app.AplicarButton = uibutton(app.EditarTab, 'push');
            app.AplicarButton.ButtonPushedFcn = createCallbackFcn(app, @AplicarButtonPushed, true);
            app.AplicarButton.Text = 'Aplicar';
            app.CarregarImagemTab = uitab(app.TabGroup);
            app.CarregarImagemTab.Title = 'Carregar Imagem';
            app.ImagemSelecionada = uiaxes(app.CarregarImagemTab);
            app.EncontrarButton = uibutton(app.CarregarImagemTab, 'push');
            app.EncontrarButton.ButtonPushedFcn = createCallbackFcn(app, @EncontrarButtonPushed, true);
            app.EncontrarButton.Text = 'Encontrar';
            app.SelecioneoarquivodaimagemLabel = uilabel(app.CarregarImagemTab);
            app.SelecioneoarquivodaimagemLabel.Text = 'Selecione o arquivo da imagem';
            app.ImagemAtual = uiaxes(app.UIFigure);
            app.OpesPanel = uipanel(app.UIFigure);
            app.OpesPanel.Title = 'Opções';
            app.VoltarButton = uibutton(app.OpesPanel, 'push');
            app.VoltarButton.Text = 'Voltar';
            app.LimparTudoButton = uibutton(app.OpesPanel, 'push');
            app.LimparTudoButton.Text = 'Limpar Tudo';
        end
    end

    methods (Access = public)

        % Construct app
        function app = trab1

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end